classdef linear_Huy_v9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        INSTRUCTIONTab                  matlab.ui.container.Tab
        HTML                            matlab.ui.control.HTML
        TESTTab                         matlab.ui.container.Tab
        DeterminantButton               matlab.ui.control.Button
        PlotSingleVectorButton          matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        PlotColVectorsButton            matlab.ui.control.Button
        SolveAugementedMatrixButton     matlab.ui.control.Button
        DestinationRowEditField         matlab.ui.control.NumericEditField
        DestinationRowEditFieldLabel    matlab.ui.control.Label
        SourceRowEditField              matlab.ui.control.NumericEditField
        RowToMultiplyEditField_2Label_2  matlab.ui.control.Label
        MultiplyFactorEditField_2       matlab.ui.control.EditField
        MultiplyFactorEditField_2Label  matlab.ui.control.Label
        AddMultipleOfSourceRowToDestinationRowButton  matlab.ui.control.Button
        MultiplyFactorEditField         matlab.ui.control.EditField
        MultiplyFactorEditFieldLabel    matlab.ui.control.Label
        RowToMultiplyEditField          matlab.ui.control.NumericEditField
        RowToMultiplyEditField_2Label   matlab.ui.control.Label
        MultiplyRowButton               matlab.ui.control.Button
        RowSwap2EditField               matlab.ui.control.NumericEditField
        RowSwap2EditFieldLabel          matlab.ui.control.Label
        RowSwap1EditField               matlab.ui.control.NumericEditField
        RowSwap1EditFieldLabel          matlab.ui.control.Label
        SwapRowButton                   matlab.ui.control.Button
        FindNullityButton               matlab.ui.control.Button
        FindRankButton                  matlab.ui.control.Button
        FindNullSpaceBasisButton        matlab.ui.control.Button
        FindColSpaceBasisButton         matlab.ui.control.Button
        InstructionsTextArea            matlab.ui.control.TextArea
        InstructionsTextAreaLabel       matlab.ui.control.Label
        RREFButton                      matlab.ui.control.Button
        GenerateMatrixButton            matlab.ui.control.Button
        FindRowSpaceBasisButton         matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        CheckSpanButton                 matlab.ui.control.Button
        CheckLinearlyIndependentButton  matlab.ui.control.Button
        ResultsEditField                matlab.ui.control.EditField
        ResultsEditFieldLabel           matlab.ui.control.Label
        ColsEditField                   matlab.ui.control.NumericEditField
        ColsEditFieldLabel              matlab.ui.control.Label
        RowsEditField                   matlab.ui.control.NumericEditField
        RowsEditFieldLabel              matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        SomeTab                         matlab.ui.container.Tab
        CHAPTER2Tab                     matlab.ui.container.Tab
        InstructionsC2                  matlab.ui.control.TextArea
        MatrixInputTableC2              matlab.ui.control.Table
        VectorBInputTableC2             matlab.ui.control.Table
        RREFResultTableC2               matlab.ui.control.Table
        ResultTableC2                   matlab.ui.control.Table
        ResultsEditFieldC2              matlab.ui.control.EditField
        RowsEditFieldC2                 matlab.ui.control.NumericEditField
        ColsEditFieldC2                 matlab.ui.control.NumericEditField
        GenerateMatrixButtonC2          matlab.ui.control.Button
        RREFSummarizeEditFieldC2        matlab.ui.control.TextArea
        InstructionsTextArea_2Label_3   matlab.ui.control.Label
        ExplanationEditFieldC2          matlab.ui.control.TextArea
        InstructionsTextArea_2Label_2   matlab.ui.control.Label
        RREFButtonC2                    matlab.ui.control.Button
        RREFAugMButtonC2                matlab.ui.control.Button
        SolveAugementedMatrixButtonC2   matlab.ui.control.Button
        InstructionsTextArea_2Label     matlab.ui.control.Label
        CheckSpanButtonC2               matlab.ui.control.Button
        CheckLinearlyIndependentButtonC2  matlab.ui.control.Button
        ResultsEditField_2Label         matlab.ui.control.Label
        ColsEditField_2Label            matlab.ui.control.Label
        RowsEditField_2Label            matlab.ui.control.Label
        CHAPTER3Tab                     matlab.ui.container.Tab
        MatrixInputTableC3              matlab.ui.control.Table
        VectorBInputTableC3             matlab.ui.control.Table
        GenerateMatrixButtonC3          matlab.ui.control.Button
        RowsEditFieldC3                 matlab.ui.control.NumericEditField
        ColsEditFieldC3                 matlab.ui.control.NumericEditField
        GetTransformationInfoButtonC3   matlab.ui.control.Button
        TransformationMatrixEditFieldC3  matlab.ui.control.TextArea
        InstructionsTextArea_2Label_6   matlab.ui.control.Label
        RREFResultTableC2_2             matlab.ui.control.Table
        ResultTableC2_2                 matlab.ui.control.Table
        ExplanationEditFieldC2_2        matlab.ui.control.TextArea
        InstructionsTextArea_2Label_5   matlab.ui.control.Label
        RREFAugMButtonC2_2              matlab.ui.control.Button
        SolveAugementedMatrixButtonC2_2  matlab.ui.control.Button
        InstructionsC2_2                matlab.ui.control.TextArea
        InstructionsTextArea_2Label_4   matlab.ui.control.Label
        CheckSpanButtonC2_2             matlab.ui.control.Button
        CheckLinearlyIndependentButtonC2_2  matlab.ui.control.Button
        ResultsEditFieldC2_2            matlab.ui.control.EditField
        ResultsEditField_2Label_2       matlab.ui.control.Label
        ColsEditField_2Label_2          matlab.ui.control.Label
        RowsEditField_2Label_2          matlab.ui.control.Label
    end

    methods (Access = private)

        % Set column and row names for any table
        function setMatrixTableNames(app, table, rows, cols)
            % Create cell arrays for column and row names
            columnNames = cell(1, cols);
            rowNames = cell(1, rows);
        
            % Set column names in the format {colname1, colname2, ...}
            for i = 1:cols
                columnNames{i} = ['Column ' num2str(i)];
            end
        
            % Set row names in the format {rowname1, rowname2, ...}
            for j = 1:rows
                rowNames{j} = ['Row ' num2str(j)];
            end
        
            % Apply the column and row names to the passed table
            table.ColumnName = columnNames;
            table.RowName = rowNames;
        end

        % Set cols name for AugM matrix
        function columnNames = setAugMTableName(app, table, rows, cols)
            % columnNames in format of {colname1, colname2, ...}
            columnNames = cell(1, cols);
            rowNames = cell(1, rows);

            % Simple for loop to get cols and rows name
            for i = 1:cols - 1
                columnNames{i} = ['Column ' num2str(i)];
            end

            for j = 1:rows
                rowNames{j} = ['Row ' num2str(j)];
            end
  
            % Last col is the vector b
            columnNames{end} = 'Vector b';
            
            % Apply the column and row names to the passed table
            table.ColumnName = columnNames;
            table.RowName = rowNames;
        end

        % Set column and row names for any table considering free and pivot variables
        function setResultTableName(app, table, pivot_columns, free_columns, rows, cols)
            % Create cell arrays for column and row names
            columnNames = cell(1, cols);
            rowNames = cell(1, rows);

            pivot_var_count = 0;
            free_var_count = 0;
        
            % Loop through each column and assign a name indicating whether it is a pivot or free variable
            for i = 1:cols-1
                if ismember(i, pivot_columns)
                    pivot_var_count = pivot_var_count + 1;
                    columnNames{i} = ['Pivot Variable ' num2str(pivot_var_count)];
                elseif ismember(i, free_columns)
                    free_var_count = free_var_count + 1;
                    columnNames{i} = ['Free Variable ' num2str(free_var_count)];
                else
                    columnNames{i} = ['Column '];
                end
            end
        
            % Last column is the vector b
            columnNames{end} = 'Vector b';
        
            % Assign row names
            for j = 1:rows
                rowNames{j} = ['Row ' num2str(j)];
            end
        
            % Apply the column and row names to the passed table
            table.ColumnName = columnNames;
            table.RowName = rowNames;
        end


        % Set cols name for Normal Matrix
        function columnNames = setMName(app, rows, cols)
            % columnNames in format of {colname1, colname2, ...}
            columnNames = cell(1, cols);
            rowNames = cell(1, rows);

            % Simple for loop to get cols name
            for i = 1:cols
                columnNames{i} = ['Column ' num2str(i)];
            end

            for j = 1:rows
                rowNames{j} = ['Row ' num2str(j)];
            end
            
            % Set the row and col names
            app.UITable.ColumnName = columnNames;
            app.UITable.RowName = rowNames;
        end
        

        % Convert the string input to numeric (CHATGPT)
        function num = stringToNumeric(~, str)
            % Strip leading and trailing spaces
            str = strtrim(str);
            
            % Pattern for matching valid numbers, fractions, and decimal numbers (including negative values)
            validPattern = '^(-?\d+(\.\d+)?)(/\d+(\.\d+)?)?$';
            
            % Check if the string matches the valid pattern for numbers or fractions
            if ~isempty(regexp(str, validPattern, 'once'))
                if contains(str, '/')
                    parts = strsplit(str, '/');
                    numerator = str2double(parts{1});
                    denominator = str2double(parts{2});
                    if denominator ~= 0 % Ensure denominator is not zero for fractions
                        num = numerator / denominator;
                        return;
                    end
                else
                    num = str2double(str); % Direct conversion for whole and decimal numbers
                    return;
                end
            end
            
            % If input is not valid according to our criteria, return NaN
            num = NaN;
        end
        

        % Function to convert String Matrix to Numeric Matrix
        function numericMatrix = getNumericMatrix(app, StringMatrix)
            % Zeros matrix as a placeholder 
            numericMatrix = zeros(size(StringMatrix));
            
            % For loop to get all the elements
            for row = 1:size(StringMatrix, 1)
                for col = 1:size(StringMatrix, 2)
                    numericMatrix(row, col) = app.stringToNumeric(StringMatrix{row, col});
                end
            end
            
        end
        
        
        % Function to convert a matrix of doubles to a string matrix
        function stringMatrix = doubleToStringMatrix(~, doubleMatrix)
            % Initialize the string matrix with the same size as the double matrix
            stringMatrix = cell(size(doubleMatrix));
            
            % Loop through each element in the double matrix
            for row = 1:size(doubleMatrix, 1)
                for col = 1:size(doubleMatrix, 2)
                    % Convert each double element to a string and store in the corresponding position
                    stringMatrix{row, col} = num2str(doubleMatrix(row, col));
                end
            end
        end
        
        % Align the stuff in the center
        function centerAlign(app)
            s = uistyle('HorizontalAlignment','center');
            addStyle(app.UITable, s, 'table','');
            addStyle(app.UITable2, s, 'table','');
            addStyle(app.UITable3, s, 'table','');
            

            % Add style for NEW Chapter tab
            addStyle(app.MatrixInputTableC2, s, 'table','');
            addStyle(app.VectorBInputTableC2, s, 'table','');
            addStyle(app.MatrixInputTableC3, s, 'table','');
            addStyle(app.VectorBInputTableC3, s, 'table','');

            addStyle(app.ResultTableC2, s, 'table','');
            addStyle(app.RREFResultTableC2, s, 'table','');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Use to set up font and format stuff
            % add in MatrixInput + VectorBInput for new chapter

            app.UITable.ColumnFormat = {'char'};
            app.UITable3.ColumnFormat = {'char'};
            app.MatrixInputTableC2.ColumnFormat = {'char'};
            app.VectorBInputTableC2.ColumnFormat = {'char'};
            app.MatrixInputTableC3.ColumnFormat = {'char'};
            app.VectorBInputTableC3.ColumnFormat = {'char'};
            app.centerAlign();

        end

        % Button pushed function: GenerateMatrixButton
        function GenerateMatrixButtonPushed(app, event)
            % Get number of rows and cols
            num_of_rows = app.RowsEditField.Value;
            num_of_cols = app.ColsEditField.Value;

            % Set name for rows and cols
            app.setMName(num_of_rows, num_of_cols);

            % Generate a String Zeros vector on the Table as a placeholder
            app.UITable.Data = string(zeros(num_of_rows, num_of_cols));

            app.UITable3.Data = string(zeros(num_of_rows, 1));
        end

        % Button pushed function: CheckSpanButton
        function CheckSpanButtonPushed(app, event)
            % Clear previous result
            app.ResultsEditField.Value = '';  % Reset the text field to empty
            
            % If there's an output table to clear, reset it
            app.UITable2.Data = [];  % Reset the output table if necessary
        
            % Get the input matrix from the user, then convert to numeric
            Matrix = app.getNumericMatrix(app.UITable.Data);
            Vector = app.getNumericMatrix(app.UITable3.Data);
            AugM = [Matrix, Vector];
        
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(AugM(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Code from Prof. Li's CheckSpan.m
                [~, pb] = rref(AugM); % get RREF of the augmented matrix AugM
                A = AugM(:, 1:end-1); % extract the coefficient matrix A from AugM
                [~, p] = rref(A);
                
                % Check if vector b is in the span of the matrix
                if length(pb) ~= length(p)
                    app.ResultsEditField.Value = "The vector b IS NOT IN the span of the given set of vectors.";
                else
                    app.ResultsEditField.Value = "The vector b IS IN the span of the given set of vectors.";
                end
            end
        end

        % Button pushed function: CheckLinearlyIndependentButton
        function CheckLinearlyIndependentButtonPushed(app, event)
            % Clear previous result
            app.ResultsEditField.Value = '';  % Reset the text field to empty
            
            % If there's an output table to clear, reset it
            app.UITable2.Data = [];  % Reset the output table if necessary
            
            % Get the input matrix from the user, then convert to numeric
            Matrix = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(Matrix(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Code from Prof. Li CheckLide.m
                [~,p] = rref(Matrix);
    
                if length(p) == size (Matrix,2) % size(A,1) gives #of rows in the matrix, and size(A,2) gives #of columns
                    app.ResultsEditField.Value = "This set of vectors is linearly INDEPENDENT!";
                else
                    app.ResultsEditField.Value = "This set of vectors is linearly DEPENDENT!";
                end
            end
        end

        % Button pushed function: RREFButton
        function RREFButtonPushed(app, event)
            % Get string Matrix, convert to numeric
            A = app.getNumericMatrix(app.UITable.Data);
            
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Compute the reduced row echelon form
                rref_A = rref(A);
                
                % Display the RREF in the second table with rational numbers
                app.UITable2.Data = rref_A; 
                
                % Set all columns to display as rational numbers {rat*num_cols}
                app.UITable2.ColumnFormat = repmat({'rat'}, 1, size(rref_A, 2));
            end
        end

        % Button pushed function: SolveAugementedMatrixButton
        function SolveAugementedMatrixButtonPushed(app, event)
            Matrix = app.getNumericMatrix(app.UITable.Data);
            Vector = app.getNumericMatrix(app.UITable3.Data);
            AugM = [Matrix, Vector];
            
            % Check if the matrix has a NaN value, return an error message
            if any(isnan(AugM(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Set the table format for displaying solutions
                app.UITable2.ColumnFormat = {'char'};
                
                % Separate matrix A and vector b
                matrix_A = AugM(:, 1:end-1);
                vector_b = AugM(:, end);
                
                % Check the dimensions of matrix A
                [num_rows, num_vars] = size(matrix_A);
                
                % Use RREF to analyze the solution space
                rrefAugM = rref(AugM);
                rank_A = rank(matrix_A);
                rank_AugM = rank(AugM);
                
                if rank_A == rank_AugM
                    % The system is consistent
                    if rank_A < num_vars
                        % Infinitely many solutions (free variables)
                        num_free_vars = num_vars - rank_A;
                        
                        % Identify pivot and free variables
                        pivot_columns = find(any(rrefAugM(:, 1:num_vars), 1));
                        free_columns = setdiff(1:num_vars, pivot_columns);
                        param_symbols = arrayfun(@(x) char('a' + x - 1), 1:length(free_columns), 'UniformOutput', false);
                        
                        % Initialize the solution as symbolic variables
                        syms(param_symbols{:});
                        solution = sym('x', [num_vars, 1]);
                        
                        % Assign the free variables to parameters
                        for i = 1:length(free_columns)
                            solution(free_columns(i)) = sym(param_symbols{i});
                        end
                        
                        % Solve for the dependent variables in terms of the free variables
                        for i = 1:rank_A
                            row = rrefAugM(i, :);
                            pivot_col = pivot_columns(i);
                            rhs = row(end);
                            dependent_sum = rhs;
                            
                            % Subtract contributions from free variables
                            for j = 1:num_vars
                                if j ~= pivot_col && row(j) ~= 0
                                    dependent_sum = dependent_sum - row(j) * solution(j);
                                end
                            end
                            solution(pivot_col) = dependent_sum;
                        end
                        
                        % Construct a message about the number of free variables
                        app.ResultsEditField.Value = sprintf('The system has infinitely many solutions with %d free variable(s).', num_free_vars);
                        
                        % Display the general solution form as strings
                        solution_strings = arrayfun(@(i) sprintf('x%d = %s', i, char(solution(i))), 1:num_vars, 'UniformOutput', false);
                        app.UITable2.Data = solution_strings;
                    else
                        % Unique solution (square or overdetermined with consistent equations)
                        solutions = rrefAugM(:, end);
                        app.ResultsEditField.Value = 'The unique solution set of Ax = b is: ';
                        
                        % Display solutions as fractions using char() for symbolic representation
                        solution_symbols = sym(solutions); % Convert solutions to symbolic
                        solution_strings = arrayfun(@(i) sprintf('x%d = %s', i, char(solution_symbols(i))), 1:num_vars, 'UniformOutput', false);
                        app.UITable2.Data = solution_strings;
                    end
                else
                    % The system is inconsistent (no solution)
                    app.ResultsEditField.Value = 'The system is inconsistent and has no solution.';
                    app.UITable2.Data = [];  % Optionally clear the solution table
                end
            end


        end

        % Button pushed function: FindRowSpaceBasisButton
        function FindRowSpaceBasisButtonPushed(app, event)
            % Get the input matrix from the user, then convert to numeric
            A = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Find the Basis of Row Space - Prof. Li
                A=sym(A); 
                B = A';
                i = colspace(B);
                app.ResultsEditField.Value = "The basis of its ROW space is: ";
                app.UITable2.Data = double(i);
            end
        end

        % Button pushed function: FindColSpaceBasisButton
        function FindColSpaceBasisButtonPushed(app, event)
            % Get string matrix, convert to numeric
            A = app.getNumericMatrix(app.UITable.Data);
            
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                A=sym(A); 
                ii = colspace(A);
                app.ResultsEditField.Value = "The basis of its COLUMN space is: ";
                app.UITable2.Data = double(ii);
            end
        end

        % Button pushed function: FindNullSpaceBasisButton
        function FindNullSpaceBasisButtonPushed(app, event)
            A = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                A=sym(A); 
                iii= colspace(null(A));
                app.ResultsEditField.Value = "The basis of its NULL space is: ";
                if isempty(iii)==0
                    app.UITable2.Data = double(iii);
                else
                    app.ResultsEditField.Value = "The basis of its NULL space is: ";
                    app.UITable2.Data = double([]);
                end
            end
        end

        % Button pushed function: FindRankButton
        function FindRankButtonPushed(app, event)
            A = app.getNumericMatrix(app.UITable.Data);
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                A=sym(A); 
                iv= rank(A);
                app.ResultsEditField.Value = 'The RANK of this matrix is: ' + string(iv);
            end
        end

        % Button pushed function: FindNullityButton
        function FindNullityButtonPushed(app, event)
            A = app.getNumericMatrix(app.UITable.Data);
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                A=sym(A); 
                iii= colspace(null(A));
                v= size(iii,2);
                app.ResultsEditField.Value = 'The NULLITY of this matrix is: ' + string(v);
            end
        end

        % Button pushed function: DeterminantButton
        function DeterminantButtonPushed(app, event)
            % Get the input matrix from the user, then convert to numeric
            Matrix = app.getNumericMatrix(app.UITable.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(Matrix(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Get the size of the matrix
                [rows, cols] = size(Matrix);
            
                % Check if the matrix is square
                if rows ~= cols
                    uialert(app.UIFigure, 'The input matrix must be square to compute the determinant.', 'Input Error');
                else
                    % Compute the determinant
                    detValue = det(Matrix);
                    app.ResultsEditField.Value = 'The Determinant of this matrix is: ' + string(detValue);
                end
            end
        end

        % Button pushed function: SwapRowButton
        function SwapRowButtonPushed(app, event)
            % Check if the matrix has a NaN value, return the error message
            M = app.getNumericMatrix(app.UITable.Data);
            if any(isnan(M(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                app.UITable.ColumnFormat = repmat({'rat'}, 1, size(M, 2));
                
                % Set orginial matrix as the output matrix if the user
                % first perform row operation
                if isempty(app.UITable.Data)
                    app.UITable.Data = M;
                end
    
                A = app.UITable.Data;
        
                row_swap_1 = app.RowSwap1EditField.Value;
                row_swap_2 = app.RowSwap2EditField.Value;
                
                B = A;
                B(row_swap_2,:) = A(row_swap_1,:);
                B(row_swap_1,:) = A(row_swap_2,:);
    
                % Update the matrix
                app.UITable.Data = B;
            end
        end

        % Button pushed function: MultiplyRowButton
        function MultiplyRowButtonPushed(app, event)
            % Check if the matrix has a NaN value, return the error message
            M = app.getNumericMatrix(app.UITable.Data);
            
            if any(isnan(M(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                app.UITable.ColumnFormat = repmat({'rat'}, 1, size(M, 2));
                
                row_to_multiply = app.RowToMultiplyEditField.Value;
                multiply_factor = eval(app.MultiplyFactorEditField.Value);
                
                B = M;
                B(row_to_multiply, :) = multiply_factor*M(row_to_multiply, :);
    
                % Update the matrix
                app.UITable.Data = app.doubleToStringMatrix(B);
            end
        end

        % Button pushed function: 
        % AddMultipleOfSourceRowToDestinationRowButton
        function AddMultipleOfSourceRowToDestinationRowButtonPushed(app, event)
            % Check if the matrix has a NaN value, return the error message
            M = app.getNumericMatrix(app.UITable.Data);
            if any(isnan(M(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                app.UITable.ColumnFormat = repmat({'rat'}, 1, size(M, 2));

                multiply_factor = eval(app.MultiplyFactorEditField_2.Value);
                source_row = app.SourceRowEditField.Value;
                destination_row = app.DestinationRowEditField.Value;
                
                B = M;
                B(destination_row,:) = multiply_factor*M(source_row,:) + M(destination_row,:);
    
                % Update the matrix
                app.UITable.Data = app.doubleToStringMatrix(B);
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            numericValue = app.stringToNumeric(newData);
            if isnan(numericValue)
                % If the input is not valid, set the cell value to NaN
                app.UITable.Data(indices(1), indices(2)) = {'NaN'};
            end
        end

        % Button pushed function: PlotColVectorsButton
        function PlotColVectorsButtonPushed(app, event)
% Clear the axes for a fresh plot
cla(app.UIAxes, 'reset');

set(app.UIFigure, 'Renderer', 'opengl');  % Ensure the figure uses OpenGL

% Prepare for plotting
hold(app.UIAxes, 'on');

% Retrieve matrix data for the vectors to be plotted
matrixData = app.getNumericMatrix(app.UITable.Data);

% Check if the vectors are 3D and if there are two vectors for the 2D plane
is2Dplane = size(app.UITable2.Data, 2) == 2;
R_dimension = size(matrixData, 1);

if R_dimension == 3 && is2Dplane
    % Basis data for the plane
    basisMatrixData = app.UITable2.Data;

    v1 = basisMatrixData(:, 1);
    v2 = basisMatrixData(:, 2);

    % Grid for the plane
    [alpha, beta] = meshgrid(-1:0.1:1, -1:0.1:1);
    
    % Points on the plane
    X = alpha * v1(1) + beta * v2(1);
    Y = alpha * v1(2) + beta * v2(2);
    Z = alpha * v1(3) + beta * v2(3);
    
    % Plot the plane
    meshPlot = mesh(app.UIAxes, X, Y, Z);
    set(meshPlot, 'FaceAlpha', 0.5, 'EdgeAlpha', 0.5);

    % Plot each vector
    for i = 1:size(matrixData, 2)
        vec = matrixData(:,i);
        norm_vec = vec * 2 / norm(vec);  % Normalize to double the unit vector
        quiver3(app.UIAxes, 0, 0, 0, norm_vec(1), norm_vec(2), norm_vec(3), ...
                'LineWidth', 2, 'Color', 'r', 'AutoScale', 'off');
    end

    % View and grid setup
    view(app.UIAxes, 3);
    grid(app.UIAxes, 'on');
    axis(app.UIAxes, 'equal');

    % Set axis limits
    xlim(app.UIAxes, [-3, 3]);
    ylim(app.UIAxes, [-3, 3]);
    zlim(app.UIAxes, [-3, 3]);

    % Set titles and labels
    title(app.UIAxes, 'Span of Column Space');
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');
    zlabel(app.UIAxes, 'Z');

else
    uialert(app.UIFigure, 'This can only visualize 2D Subspace in 3D. Row/Col Basis should only have 2 3-D vectors', 'Input Error');
end

        end

        % Cell edit callback: UITable3
        function UITable3CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            numericValue = app.stringToNumeric(newData);
            if isnan(numericValue)
                % If the input is not valid, set the cell value to NaN
                app.UITable3.Data(indices(1), indices(2)) = {'NaN'};
            end
            
        end

        % Button pushed function: PlotSingleVectorButton
        function PlotSingleVectorButtonPushed(app, event)
            vec = app.getNumericMatrix(app.UITable3.Data);
            % If in span plot orange, if not plot blue.

            basisMatrix = app.UITable2.Data;

            AugM = [basisMatrix, vec];

            % Code from Prof. Li CheckSpan.m
            [~ ,pb] = rref(AugM); % get RREF of the augmented matrix AugM
            A=AugM(:,1:end-1); % extract the coefficient matrix A from AugM;
            [~ ,p] = rref(A);

            if length(pb) == length(p) 
                vec = vec(:,1);
                % Normalize the vector by 2 * unit vector 
                norm_vec = vec*2 / norm(vec);
                % Plot 3D normalized vectors with thicker lines
                quiver3(app.UIAxes, 0, 0, 0, norm_vec(1), norm_vec(2), norm_vec(3), ...
        'LineWidth', 2, 'Color', [1, 0.5, 0], 'AutoScale', 'off');
            elseif length(pb) ~= length(p)
                vec = vec(:,1);
                % Normalize the vector by 2 * unit vector 
                norm_vec = vec*2 / norm(vec);
                % Plot 3D normalized vectors with thicker lines
                quiver3(app.UIAxes, 0, 0, 0, norm_vec(1), norm_vec(2), norm_vec(3), ...
                        'LineWidth', 2, 'Color', 'b', 'AutoScale', 'off');
            end

        end

        % Button pushed function: GenerateMatrixButtonC2
        function GenerateMatrixButtonC2Pushed(app, event)
            % Get number of rows and cols
            num_of_rows = app.RowsEditFieldC2.Value;
            num_of_cols = app.ColsEditFieldC2.Value;

            % Set name for rows and cols
            app.setMatrixTableNames(app.MatrixInputTableC2, num_of_rows, num_of_cols);

            % Generate a String Zeros vector on the Table as a placeholder
            app.MatrixInputTableC2.Data = string(zeros(num_of_rows, num_of_cols));

            app.VectorBInputTableC2.Data = string(zeros(num_of_rows, 1));
        end

        % Cell edit callback: VectorBInputTableC2
        function VectorBInputTableC2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            numericValue = app.stringToNumeric(newData);
            if isnan(numericValue)
                % If the input is not valid, set the cell value to NaN
                app.VectorBInputTableC2.Data(indices(1), indices(2)) = {'NaN'};
            end
        end

        % Cell edit callback: MatrixInputTableC2
        function MatrixInputTableC2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            numericValue = app.stringToNumeric(newData);
            if isnan(numericValue)
                % If the input is not valid, set the cell value to NaN
                app.MatrixInputTableC2.Data(indices(1), indices(2)) = {'NaN'};
            end
        end

        % Button pushed function: SolveAugementedMatrixButtonC2
        function SolveAugementedMatrixButtonC2Pushed(app, event)
% Retrieve the matrix and vector from the UI tables
Matrix = app.getNumericMatrix(app.MatrixInputTableC2.Data);
Vector = app.getNumericMatrix(app.VectorBInputTableC2.Data);
AugM = [Matrix, Vector];

% Check if the matrix has a NaN value, return an error message
if any(isnan(AugM(:)))
    uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
else
    % Set the table format for displaying solutions
    app.ResultTableC2.ColumnFormat = {'char'};

    % Separate matrix A and vector b
    matrix_A = AugM(:, 1:end-1);
    vector_b = AugM(:, end);

    % Check the dimensions of matrix A
    [num_rows, num_vars] = size(matrix_A);

    % Use RREF to analyze the solution space
    rrefAugM = rref(AugM);
    rank_A = rank(matrix_A);
    rank_AugM = rank(AugM);

    if rank_A == rank_AugM
        % The system is consistent
        if rank_A < num_vars
            % Infinitely many solutions (free variables)
            num_free_vars = num_vars - rank_A;

            % Determining the RREF of the augmented matrix and identifying pivot and free columns
            pivot_columns = find(any(rrefAugM(:, 1:num_vars) == 1, 1));
            free_columns = setdiff(1:num_vars, pivot_columns);
            
            % Set column and row names for the result table based on pivot and free columns
            setResultTableName(app, app.ResultTableC2, pivot_columns, free_columns, num_rows, num_vars + 1);


            param_symbols = arrayfun(@(x) sym(['x' num2str(free_columns(x))]), 1:length(free_columns), 'UniformOutput', false);

            % Initialize the solution as a column of symbolic variables
            solution = sym('x', [num_vars, 1]);

            % Assign the free variables to parameters
            for i = 1:length(free_columns)
                solution(free_columns(i)) = param_symbols{i};
            end

            % Solve for the dependent variables in terms of the free variables
            for i = 1:rank_A
                row = rrefAugM(i, :);
                pivot_col = pivot_columns(i);
                rhs = row(end);
                dependent_sum = rhs;

                % Subtract contributions from free variables
                for j = 1:num_vars
                    if j ~= pivot_col && row(j) ~= 0
                        dependent_sum = dependent_sum - row(j) * solution(j);
                    end
                end
                solution(pivot_col) = dependent_sum;
            end

            % Substitute dependent pivot variables into earlier pivot variables
            for i = rank_A:-1:1
                current_pivot = pivot_columns(i);
                % Loop through earlier pivots and substitute current pivot value
                for j = (i - 1):-1:1
                    earlier_pivot = pivot_columns(j);
                    solution(earlier_pivot) = simplify(subs(solution(earlier_pivot), sym(['x' num2str(current_pivot)]), solution(current_pivot)));
                end
            end

            % Construct a message about the number of free variables
            app.ResultsEditFieldC2.Value = sprintf('The system has infinitely many solutions with %d free variable(s).', num_free_vars);

            % Display the general solution form as strings
            solution_strings = arrayfun(@(i) sprintf('x%d = %s', i, char(solution(i))), 1:num_vars, 'UniformOutput', false);
            app.ResultTableC2.Data = reshape(solution_strings, 1, []);
        else
            % Unique solution (square or overdetermined with consistent equations)
            solutions = rrefAugM(:, end);
            app.ResultsEditFieldC2.Value = 'The unique solution set of Ax = b is: ';

            % Display solutions as fractions using char() for symbolic representation
            solution_symbols = sym(solutions); % Convert solutions to symbolic
            solution_strings = arrayfun(@(i) sprintf('x%d = %s', i, char(solution_symbols(i))), 1:num_vars, 'UniformOutput', false);
            app.ResultTableC2.Data = reshape(solution_strings, 1, []);
        end
    else
        % The system is inconsistent (no solution)
        app.ResultsEditFieldC2.Value = 'The system is inconsistent and has no solution.';
        app.ResultTableC2.Data = [];  % Optionally clear the solution table
    end
end


% Explanation stuff

AugM = [Matrix, Vector];
% Compute the reduced row echelon form
rref_AugM = rref(AugM);
rref_A = rref(Matrix);

% Get num_of_rows and num_of_cols for RREFAugM matrix
[num_rows, num_cols] = size(rref_AugM);

has_no_solution = any(all(rref_AugM(:, 1:num_cols-1) == 0, 2) & rref_AugM(:, end) ~= 0);
if has_no_solution
    app.ExplanationEditFieldC2.Value = 'The system has no solution because it contains a row with all zeros in the coefficient part and a non-zero in the augmented part. (Check with RREF AugM)';
else
    % Find pivot columns by identifying columns with leading 1s
    pivot_columns = find(any(rref_A(:, 1:end) == 1, 1));
    
    % Get the number of pivot columns
    num_pivots = length(pivot_columns);

    if num_pivots < num_cols - 1 && num_pivots > 0
        num_free_vars = num_cols - 1 - num_pivots;
        explanation = sprintf('The system has infinitely many solutions because the matrix has %d columns, but only %d pivot columns at indices %s and therefore %d free variable(s). (Check with RREF AugM)', num_cols - 1, num_pivots, mat2str(pivot_columns), num_free_vars);
        app.ExplanationEditFieldC2.Value = explanation;
    
    elseif num_pivots == 0
        num_free_vars = num_cols - 1 - num_pivots;
        explanation = sprintf('The system has infinitely many solutions because the matrix has %d columns, but only %d pivot columns and therefore %d free variable(s). (Check with RREF AugM)', num_cols - 1, num_pivots, num_free_vars);
        app.ExplanationEditFieldC2.Value = explanation;

    elseif num_pivots == num_cols - 1
        explanation = sprintf('The system has unique solution because the matrix has %d columns, and also has the same number of %d pivot columns and %d free variable(s). (Check with RREF AugM)', num_cols - 1, num_pivots, 0);
        app.ExplanationEditFieldC2.Value = explanation;
    end

end
        end

        % Button pushed function: RREFButtonC2
        function RREFButtonC2Pushed(app, event)
            % Get string Matrix, convert to numeric
            A = app.getNumericMatrix(app.MatrixInputTableC2.Data);
            
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(A(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Compute the reduced row echelon form
                rref_A = rref(A);

                % Get num_of_rows and num_of_cols
                [num_of_rows, num_of_cols] = size(rref_A);

                % Find pivot columns by identifying columns with leading 1s
                pivot_columns = find(any(rref_A(:, 1:end) == 1, 1));
                
                % Get the number of pivot columns
                num_pivots = length(pivot_columns);

                if num_pivots < num_of_cols && num_pivots > 0
                    num_free_vars = num_of_cols - num_pivots;
                    explanation = sprintf('The matrix has %d columns, and %d pivot columns at indices %s and therefore %d free variable(s).', num_of_cols, num_pivots, mat2str(pivot_columns), num_free_vars);
                    app.RREFSummarizeEditFieldC2.Value = explanation;
                
                elseif num_pivots == 0
                    num_free_vars = num_of_cols - num_pivots;
                    explanation = sprintf('The matrix has %d columns, and only %d pivot columns and therefore %d free variable(s).', num_of_cols, num_pivots, num_free_vars);
                    app.RREFSummarizeEditFieldC2.Value = explanation;

                elseif num_pivots == num_of_cols
                    explanation = sprintf('The matrix has %d columns, and also has the same number of %d pivot columns and %d free variable(s).', num_of_cols, num_pivots, 0);
                    app.RREFSummarizeEditFieldC2.Value = explanation;
                end
                
                % Format row and cols names
                app.setMatrixTableNames(app.RREFResultTableC2, num_of_rows, num_of_cols);
                
                % Display the RREF in the second table with rational numbers
                app.RREFResultTableC2.Data = rref_A; 
                
                % Set all columns to display as rational numbers {rat*num_cols}
                app.RREFResultTableC2.ColumnFormat = repmat({'rat'}, 1, size(rref_A, 2));
            end
        end

        % Button pushed function: RREFAugMButtonC2
        function RREFAugMButtonC2Pushed(app, event)
            Matrix = app.getNumericMatrix(app.MatrixInputTableC2.Data);
            Vector = app.getNumericMatrix(app.VectorBInputTableC2.Data);
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(Matrix(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            elseif any(isnan(Vector(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                AugM = [Matrix, Vector];
                % Compute the reduced row echelon form
                rref_AugM = rref(AugM);
                rref_A = rref(Matrix);

                % Get num_of_rows and num_of_cols for RREFAugM matrix
                [num_rows, num_cols] = size(rref_AugM);

                % Find pivot columns by identifying columns with leading 1s
                pivot_columns = find(any(rref_A(:, 1:end) == 1, 1));
                
                % Get the number of pivot columns
                num_pivots = length(pivot_columns);

                if num_pivots < num_cols - 1 && num_pivots > 0
                    num_free_vars = num_cols - 1 - num_pivots;
                    explanation = sprintf('The matrix has %d columns, and %d pivot columns at indices %s and therefore %d free variable(s).', num_cols - 1, num_pivots, mat2str(pivot_columns), num_free_vars);
                    app.RREFSummarizeEditFieldC2.Value = explanation;
                
                elseif num_pivots == 0
                    num_free_vars = num_cols - 1 - num_pivots;
                    explanation = sprintf('The matrix has %d columns, and only %d pivot columns and therefore %d free variable(s).', num_cols - 1, num_pivots, num_free_vars);
                    app.RREFSummarizeEditFieldC2.Value = explanation;

                elseif num_pivots == num_cols - 1
                    explanation = sprintf('The matrix has %d columns, and also has the same number of %d pivot columns and %d free variable(s).', num_cols - 1, num_pivots, 0);
                    app.RREFSummarizeEditFieldC2.Value = explanation;
                end
                
                % set rows and cols name
                app.setAugMTableName(app.RREFResultTableC2, num_rows, num_cols)
                
                % Display the RREF in the second table with rational numbers
                app.RREFResultTableC2.Data = rref_AugM; 
                
                % Set all columns to display as rational numbers {rat*num_cols}
                app.RREFResultTableC2.ColumnFormat = repmat({'rat'}, 1, size(rref_AugM, 2));
            end
        end

        % Button pushed function: CheckLinearlyIndependentButtonC2
        function CheckLinearlyIndependentButtonC2Pushed(app, event)
            % Clear previous result
            app.ResultsEditFieldC2.Value = '';  % Reset the text field to empty
            
            % If there's an output table to clear, reset it
            app.ResultTableC2.Data = [];  % Reset the output table if necessary
            
            % Get the input matrix from the user, then convert to numeric
            Matrix = app.getNumericMatrix(app.MatrixInputTableC2.Data);

            % Check if the matrix has a NaN value, return the error message
            if any(isnan(Matrix(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else

                rref_A = rref(Matrix);
                [num_rows, num_cols] = size(rref_A);

                % Find pivot columns by identifying columns with leading 1s
                pivot_columns = find(any(rref_A(:, 1:end) == 1, 1));
                    
                % Get the number of pivot columns
                num_pivots = length(pivot_columns);
                
                % Code from Prof. Li CheckLide.m
                [~,p] = rref(Matrix);
    
                if length(p) == size(Matrix,2) % size(A,1) gives #of rows in the matrix, and size(A,2) gives #of columns
                    app.ResultsEditFieldC2.Value = "This set of vectors is linearly INDEPENDENT";
                    explanation = sprintf('This set of vectors is linearly INDEPENDENT because the matrix has %d columns, and also has the same number of %d pivot columns. (Check using RREF)', num_cols, num_pivots);
                    app.ExplanationEditFieldC2.Value = explanation;

                else
                    app.ResultsEditFieldC2.Value = "This set of vectors is linearly DEPENDENT!";
                    if num_pivots > 0
                        explanation = sprintf('This set of vectors is linearly DEPENDENT because the matrix has %d columns, but only %d pivot columns at indices %s. (Check using RREF)', num_cols, num_pivots, mat2str(pivot_columns));
                        app.ExplanationEditFieldC2.Value = explanation;
                    elseif num_pivots == 0
                        explanation = sprintf('This set of vectors is linearly DEPENDENT because the matrix has %d columns, but only %d pivot columns. (Check using RREF)', num_cols, num_pivots);
                        app.ExplanationEditFieldC2.Value = explanation;
                    end
                end
            end
        end

        % Button pushed function: CheckSpanButtonC2
        function CheckSpanButtonC2Pushed(app, event)
            % Clear previous result
            app.ResultsEditFieldC2.Value = '';  % Reset the text field to empty
            
            % If there's an output table to clear, reset it
            app.ResultTableC2.Data = [];  % Reset the output table if necessary
        
            % Get the input matrix from the user, then convert to numeric
            Matrix = app.getNumericMatrix(app.MatrixInputTableC2.Data);
            Vector = app.getNumericMatrix(app.VectorBInputTableC2.Data);
            AugM = [Matrix, Vector];
        
            % Check if the matrix has a NaN value, return the error message
            if any(isnan(AugM(:)))
                uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
            else
                % Code from Prof. Li's CheckSpan.m
                [~, pb] = rref(AugM); % get RREF of the augmented matrix AugM
                A = AugM(:, 1:end-1); % extract the coefficient matrix A from AugM
                [~, p] = rref(A);
                
                % Check if vector b is in the span of the matrix
                if length(pb) ~= length(p)
                    app.ResultsEditFieldC2.Value = "The vector b IS NOT IN the span of the given set of vectors";
                    app.ExplanationEditFieldC2.Value = "The vector b IS NOT IN the span of the given set of vectors since there is no solution for the Augmented Matrix [A|b] (Check using SolveAugmenetedMatrix).";
                else
                    app.ResultsEditFieldC2.Value = "The vector b IS IN the span of the given set of vectors";
                    app.ExplanationEditFieldC2.Value = "The vector b IS IN the span of the given set of vectors since there is a solution for the Augmented Matrix [A|b] (Check using SolveAugmenetedMatrix).";
                end
            end
        end

        % Cell edit callback: MatrixInputTableC3
        function MatrixInputTableC3CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            numericValue = app.stringToNumeric(newData);
            if isnan(numericValue)
                % If the input is not valid, set the cell value to NaN
                app.MatrixInputTableC3.Data(indices(1), indices(2)) = {'NaN'};
            end
        end

        % Cell edit callback: VectorBInputTableC3
        function VectorBInputTableC3CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            numericValue = app.stringToNumeric(newData);
            if isnan(numericValue)
                % If the input is not valid, set the cell value to NaN
                app.VectorBInputTableC3.Data(indices(1), indices(2)) = {'NaN'};
            end
            
        end

        % Button pushed function: GenerateMatrixButtonC3
        function GenerateMatrixButtonC3Pushed(app, event)
            % Get number of rows and cols
            num_of_rows = app.RowsEditFieldC3.Value;
            num_of_cols = app.ColsEditFieldC3.Value;

            % Set name for rows and cols
            app.setMatrixTableNames(app.MatrixInputTableC3, num_of_rows, num_of_cols);

            % Generate a String Zeros vector on the Table as a placeholder
            app.MatrixInputTableC3.Data = string(zeros(num_of_rows, num_of_cols));

            app.VectorBInputTableC3.Data = string(zeros(num_of_rows, 1));            
        end

        % Button pushed function: GetTransformationInfoButtonC3
        function GetTransformationInfoButtonC3Pushed(app, event)
        % Get matrix A from user input, and convert to numeric format
        A = app.getNumericMatrix(app.MatrixInputTableC3.Data);
        
        % Check if the matrix has any NaN values, which would indicate invalid input
        if any(isnan(A(:)))
            uialert(app.UIFigure, 'The matrix contains at least one NaN value', 'Input Error');
        else
            % Determine the dimensions of the transformation matrix A
            [n, m] = size(A);  % Rows in A correspond to n, columns to m
            
            % Determine Domain and Codomain from the dimensions of A
            domain = sprintf('Domain: R^%d', m);      % Domain is R^m
            codomain = sprintf('Codomain: R^%d', n);  % Codomain is R^n
    
            % Explain domain and codomain
            explanation = sprintf(['Given the transformation matrix A of size %dx%d with %d row and %d cols, ', ...
                                   'the domain of T is %s, meaning T acts on vectors with %d components. ', ...
                                   'The codomain of T is %s, meaning the result of T(x) is a vector with %d components.'], ...
                                   n, m, n, m, domain, m, codomain, n);
    
            % Display the explanation in the designated text area
            app.TransformationMatrixEditFieldC3.Value = explanation;
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.INSTRUCTIONTab = uitab(app.TabGroup);
            app.INSTRUCTIONTab.Title = 'INSTRUCTION';
            app.HTML = uihtml(app.INSTRUCTIONTab);
            app.HTML.HTMLSource = 'test.html';
            app.TESTTab = uitab(app.TabGroup);
            app.TESTTab.Title = 'TEST';
            app.UIAxes = uiaxes(app.TESTTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.TESTTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.RowsEditFieldLabel = uilabel(app.TESTTab);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.TESTTab, 'numeric');
            app.ColsEditFieldLabel = uilabel(app.TESTTab);
            app.ColsEditFieldLabel.Text = 'Cols';
            app.ColsEditField = uieditfield(app.TESTTab, 'numeric');
            app.ResultsEditFieldLabel = uilabel(app.TESTTab);
            app.ResultsEditFieldLabel.Text = 'Results';
            app.ResultsEditField = uieditfield(app.TESTTab, 'text');
            app.CheckLinearlyIndependentButton = uibutton(app.TESTTab, 'push');
            app.CheckLinearlyIndependentButton.ButtonPushedFcn = createCallbackFcn(app, @CheckLinearlyIndependentButtonPushed, true);
            app.CheckLinearlyIndependentButton.Text = 'CheckLinearlyIndependent';
            app.CheckSpanButton = uibutton(app.TESTTab, 'push');
            app.CheckSpanButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSpanButtonPushed, true);
            app.CheckSpanButton.Text = 'CheckSpan';
            app.UITable2 = uitable(app.TESTTab);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable2.RowName = {};
            app.FindRowSpaceBasisButton = uibutton(app.TESTTab, 'push');
            app.FindRowSpaceBasisButton.ButtonPushedFcn = createCallbackFcn(app, @FindRowSpaceBasisButtonPushed, true);
            app.FindRowSpaceBasisButton.Text = 'FindRowSpaceBasis';
            app.GenerateMatrixButton = uibutton(app.TESTTab, 'push');
            app.GenerateMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatrixButtonPushed, true);
            app.GenerateMatrixButton.Text = 'GenerateMatrix';
            app.RREFButton = uibutton(app.TESTTab, 'push');
            app.RREFButton.ButtonPushedFcn = createCallbackFcn(app, @RREFButtonPushed, true);
            app.RREFButton.Text = 'RREF';
            app.InstructionsTextAreaLabel = uilabel(app.TESTTab);
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.TESTTab);
            app.InstructionsTextArea.Value = {'1. Input number of rows and and number of cols of the input box.'; '2. Generate a 0s matrix '; '3. Input number by hand'; '4. Use button below to check attribute'};
            app.FindColSpaceBasisButton = uibutton(app.TESTTab, 'push');
            app.FindColSpaceBasisButton.ButtonPushedFcn = createCallbackFcn(app, @FindColSpaceBasisButtonPushed, true);
            app.FindColSpaceBasisButton.Text = 'FindColSpaceBasis';
            app.FindNullSpaceBasisButton = uibutton(app.TESTTab, 'push');
            app.FindNullSpaceBasisButton.ButtonPushedFcn = createCallbackFcn(app, @FindNullSpaceBasisButtonPushed, true);
            app.FindNullSpaceBasisButton.Text = 'FindNullSpaceBasis';
            app.FindRankButton = uibutton(app.TESTTab, 'push');
            app.FindRankButton.ButtonPushedFcn = createCallbackFcn(app, @FindRankButtonPushed, true);
            app.FindRankButton.Text = 'FindRank';
            app.FindNullityButton = uibutton(app.TESTTab, 'push');
            app.FindNullityButton.ButtonPushedFcn = createCallbackFcn(app, @FindNullityButtonPushed, true);
            app.FindNullityButton.Text = 'FindNullity';
            app.SwapRowButton = uibutton(app.TESTTab, 'push');
            app.SwapRowButton.ButtonPushedFcn = createCallbackFcn(app, @SwapRowButtonPushed, true);
            app.SwapRowButton.Text = 'SwapRow';
            app.RowSwap1EditFieldLabel = uilabel(app.TESTTab);
            app.RowSwap1EditFieldLabel.Text = 'RowSwap1';
            app.RowSwap1EditField = uieditfield(app.TESTTab, 'numeric');
            app.RowSwap2EditFieldLabel = uilabel(app.TESTTab);
            app.RowSwap2EditFieldLabel.Text = 'RowSwap2';
            app.RowSwap2EditField = uieditfield(app.TESTTab, 'numeric');
            app.MultiplyRowButton = uibutton(app.TESTTab, 'push');
            app.MultiplyRowButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplyRowButtonPushed, true);
            app.MultiplyRowButton.Text = 'MultiplyRow';
            app.RowToMultiplyEditField_2Label = uilabel(app.TESTTab);
            app.RowToMultiplyEditField_2Label.Text = 'RowToMultiply';
            app.RowToMultiplyEditField = uieditfield(app.TESTTab, 'numeric');
            app.MultiplyFactorEditFieldLabel = uilabel(app.TESTTab);
            app.MultiplyFactorEditFieldLabel.Text = 'MultiplyFactor';
            app.MultiplyFactorEditField = uieditfield(app.TESTTab, 'text');
            app.MultiplyFactorEditField.Value = '0';
            app.AddMultipleOfSourceRowToDestinationRowButton = uibutton(app.TESTTab, 'push');
            app.AddMultipleOfSourceRowToDestinationRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddMultipleOfSourceRowToDestinationRowButtonPushed, true);
            app.AddMultipleOfSourceRowToDestinationRowButton.Text = 'AddMultipleOfSourceRowToDestinationRow';
            app.MultiplyFactorEditField_2Label = uilabel(app.TESTTab);
            app.MultiplyFactorEditField_2Label.Text = 'MultiplyFactor';
            app.MultiplyFactorEditField_2 = uieditfield(app.TESTTab, 'text');
            app.MultiplyFactorEditField_2.Value = '0';
            app.RowToMultiplyEditField_2Label_2 = uilabel(app.TESTTab);
            app.RowToMultiplyEditField_2Label_2.Text = 'SourceRow';
            app.SourceRowEditField = uieditfield(app.TESTTab, 'numeric');
            app.DestinationRowEditFieldLabel = uilabel(app.TESTTab);
            app.DestinationRowEditFieldLabel.Text = 'DestinationRow';
            app.DestinationRowEditField = uieditfield(app.TESTTab, 'numeric');
            app.SolveAugementedMatrixButton = uibutton(app.TESTTab, 'push');
            app.SolveAugementedMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @SolveAugementedMatrixButtonPushed, true);
            app.SolveAugementedMatrixButton.Text = 'SolveAugementedMatrix';
            app.PlotColVectorsButton = uibutton(app.TESTTab, 'push');
            app.PlotColVectorsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotColVectorsButtonPushed, true);
            app.PlotColVectorsButton.Text = 'PlotColVectors';
            app.UITable3 = uitable(app.TESTTab);
            app.UITable3.ColumnName = {'Vector b'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = true;
            app.UITable3.CellEditCallback = createCallbackFcn(app, @UITable3CellEdit, true);
            app.PlotSingleVectorButton = uibutton(app.TESTTab, 'push');
            app.PlotSingleVectorButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSingleVectorButtonPushed, true);
            app.PlotSingleVectorButton.Text = 'PlotSingleVector';
            app.DeterminantButton = uibutton(app.TESTTab, 'push');
            app.DeterminantButton.ButtonPushedFcn = createCallbackFcn(app, @DeterminantButtonPushed, true);
            app.DeterminantButton.Text = 'Determinant';
            app.SomeTab = uitab(app.TabGroup);
            app.SomeTab.Title = 'SomeTab';
            app.CHAPTER2Tab = uitab(app.TabGroup);
            app.CHAPTER2Tab.Title = 'CHAPTER 2 ';
            app.RowsEditField_2Label = uilabel(app.CHAPTER2Tab);
            app.RowsEditField_2Label.Text = 'Rows';
            app.ColsEditField_2Label = uilabel(app.CHAPTER2Tab);
            app.ColsEditField_2Label.Text = 'Cols';
            app.ResultsEditField_2Label = uilabel(app.CHAPTER2Tab);
            app.ResultsEditField_2Label.Text = 'Results';
            app.CheckLinearlyIndependentButtonC2 = uibutton(app.CHAPTER2Tab, 'push');
            app.CheckLinearlyIndependentButtonC2.ButtonPushedFcn = createCallbackFcn(app, @CheckLinearlyIndependentButtonC2Pushed, true);
            app.CheckLinearlyIndependentButtonC2.Text = 'CheckLinearlyIndependent';
            app.CheckSpanButtonC2 = uibutton(app.CHAPTER2Tab, 'push');
            app.CheckSpanButtonC2.ButtonPushedFcn = createCallbackFcn(app, @CheckSpanButtonC2Pushed, true);
            app.CheckSpanButtonC2.Text = 'CheckSpan';
            app.InstructionsTextArea_2Label = uilabel(app.CHAPTER2Tab);
            app.InstructionsTextArea_2Label.Text = 'Instructions';
            app.SolveAugementedMatrixButtonC2 = uibutton(app.CHAPTER2Tab, 'push');
            app.SolveAugementedMatrixButtonC2.ButtonPushedFcn = createCallbackFcn(app, @SolveAugementedMatrixButtonC2Pushed, true);
            app.SolveAugementedMatrixButtonC2.Text = 'SolveAugementedMatrix';
            app.RREFAugMButtonC2 = uibutton(app.CHAPTER2Tab, 'push');
            app.RREFAugMButtonC2.ButtonPushedFcn = createCallbackFcn(app, @RREFAugMButtonC2Pushed, true);
            app.RREFAugMButtonC2.Text = 'RREF AugM';
            app.RREFButtonC2 = uibutton(app.CHAPTER2Tab, 'push');
            app.RREFButtonC2.ButtonPushedFcn = createCallbackFcn(app, @RREFButtonC2Pushed, true);
            app.RREFButtonC2.Text = 'RREF';
            app.InstructionsTextArea_2Label_2 = uilabel(app.CHAPTER2Tab);
            app.InstructionsTextArea_2Label_2.Text = 'Explanation';
            app.ExplanationEditFieldC2 = uitextarea(app.CHAPTER2Tab);
            app.InstructionsTextArea_2Label_3 = uilabel(app.CHAPTER2Tab);
            app.InstructionsTextArea_2Label_3.Text = 'Summarize of RREF';
            app.RREFSummarizeEditFieldC2 = uitextarea(app.CHAPTER2Tab);
            app.GenerateMatrixButtonC2 = uibutton(app.CHAPTER2Tab, 'push');
            app.GenerateMatrixButtonC2.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatrixButtonC2Pushed, true);
            app.GenerateMatrixButtonC2.Text = 'GenerateMatrix';
            app.ColsEditFieldC2 = uieditfield(app.CHAPTER2Tab, 'numeric');
            app.RowsEditFieldC2 = uieditfield(app.CHAPTER2Tab, 'numeric');
            app.ResultsEditFieldC2 = uieditfield(app.CHAPTER2Tab, 'text');
            app.ResultTableC2 = uitable(app.CHAPTER2Tab);
            app.ResultTableC2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.ResultTableC2.RowName = {};
            app.RREFResultTableC2 = uitable(app.CHAPTER2Tab);
            app.RREFResultTableC2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.RREFResultTableC2.RowName = {};
            app.VectorBInputTableC2 = uitable(app.CHAPTER2Tab);
            app.VectorBInputTableC2.ColumnName = {'Vector b'};
            app.VectorBInputTableC2.RowName = {};
            app.VectorBInputTableC2.ColumnEditable = true;
            app.VectorBInputTableC2.CellEditCallback = createCallbackFcn(app, @VectorBInputTableC2CellEdit, true);
            app.MatrixInputTableC2 = uitable(app.CHAPTER2Tab);
            app.MatrixInputTableC2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.MatrixInputTableC2.RowName = {};
            app.MatrixInputTableC2.ColumnEditable = true;
            app.MatrixInputTableC2.CellEditCallback = createCallbackFcn(app, @MatrixInputTableC2CellEdit, true);
            app.InstructionsC2 = uitextarea(app.CHAPTER2Tab);
            app.InstructionsC2.Value = {'1. Input number of rows and and number of cols of the input box.'; '2. Generate a 0s matrix '; '3. Input number by hand'; '4. Use button below to check attribute'};
            app.CHAPTER3Tab = uitab(app.TabGroup);
            app.CHAPTER3Tab.Title = 'CHAPTER 3';
            app.RowsEditField_2Label_2 = uilabel(app.CHAPTER3Tab);
            app.RowsEditField_2Label_2.Text = 'Rows';
            app.ColsEditField_2Label_2 = uilabel(app.CHAPTER3Tab);
            app.ColsEditField_2Label_2.Text = 'Cols';
            app.ResultsEditField_2Label_2 = uilabel(app.CHAPTER3Tab);
            app.ResultsEditField_2Label_2.Text = 'Results';
            app.ResultsEditFieldC2_2 = uieditfield(app.CHAPTER3Tab, 'text');
            app.CheckLinearlyIndependentButtonC2_2 = uibutton(app.CHAPTER3Tab, 'push');
            app.CheckLinearlyIndependentButtonC2_2.Text = 'CheckLinearlyIndependent';
            app.CheckSpanButtonC2_2 = uibutton(app.CHAPTER3Tab, 'push');
            app.CheckSpanButtonC2_2.Text = 'CheckSpan';
            app.InstructionsTextArea_2Label_4 = uilabel(app.CHAPTER3Tab);
            app.InstructionsTextArea_2Label_4.Text = 'Instructions';
            app.InstructionsC2_2 = uitextarea(app.CHAPTER3Tab);
            app.InstructionsC2_2.Value = {'Linear Transformation: T(x) = Ax'; 'Given A the transformation matrix, students need to know domain and codomain, one-to-one, onto.'; 'Also visualization at most in 3D, mostly 2D to 3D.'};
            app.SolveAugementedMatrixButtonC2_2 = uibutton(app.CHAPTER3Tab, 'push');
            app.SolveAugementedMatrixButtonC2_2.Text = 'SolveAugementedMatrix';
            app.RREFAugMButtonC2_2 = uibutton(app.CHAPTER3Tab, 'push');
            app.RREFAugMButtonC2_2.Text = 'RREF AugM';
            app.InstructionsTextArea_2Label_5 = uilabel(app.CHAPTER3Tab);
            app.InstructionsTextArea_2Label_5.Text = 'Explanation';
            app.ExplanationEditFieldC2_2 = uitextarea(app.CHAPTER3Tab);
            app.ResultTableC2_2 = uitable(app.CHAPTER3Tab);
            app.ResultTableC2_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.ResultTableC2_2.RowName = {};
            app.RREFResultTableC2_2 = uitable(app.CHAPTER3Tab);
            app.RREFResultTableC2_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.RREFResultTableC2_2.RowName = {};
            app.InstructionsTextArea_2Label_6 = uilabel(app.CHAPTER3Tab);
            app.InstructionsTextArea_2Label_6.Text = 'Summarize of Transformation Matrix';
            app.TransformationMatrixEditFieldC3 = uitextarea(app.CHAPTER3Tab);
            app.GetTransformationInfoButtonC3 = uibutton(app.CHAPTER3Tab, 'push');
            app.GetTransformationInfoButtonC3.ButtonPushedFcn = createCallbackFcn(app, @GetTransformationInfoButtonC3Pushed, true);
            app.GetTransformationInfoButtonC3.Text = 'GetTransformationInfo';
            app.ColsEditFieldC3 = uieditfield(app.CHAPTER3Tab, 'numeric');
            app.RowsEditFieldC3 = uieditfield(app.CHAPTER3Tab, 'numeric');
            app.GenerateMatrixButtonC3 = uibutton(app.CHAPTER3Tab, 'push');
            app.GenerateMatrixButtonC3.ButtonPushedFcn = createCallbackFcn(app, @GenerateMatrixButtonC3Pushed, true);
            app.GenerateMatrixButtonC3.Text = 'GenerateTransformationMatrix';
            app.VectorBInputTableC3 = uitable(app.CHAPTER3Tab);
            app.VectorBInputTableC3.ColumnName = {'Vector b'};
            app.VectorBInputTableC3.RowName = {};
            app.VectorBInputTableC3.ColumnEditable = true;
            app.VectorBInputTableC3.CellEditCallback = createCallbackFcn(app, @VectorBInputTableC3CellEdit, true);
            app.MatrixInputTableC3 = uitable(app.CHAPTER3Tab);
            app.MatrixInputTableC3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.MatrixInputTableC3.RowName = {};
            app.MatrixInputTableC3.ColumnEditable = true;
            app.MatrixInputTableC3.CellEditCallback = createCallbackFcn(app, @MatrixInputTableC3CellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = linear_Huy_v9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end